classdef GearDesignApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Button_3                        matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        ResetButton                     matlab.ui.control.Button
        SolveButton                     matlab.ui.control.Button
        OUTPUTPanel                     matlab.ui.container.Panel
        CenterDistanceCmEditField       matlab.ui.control.NumericEditField
        CenterDistanceCmEditFieldLabel  matlab.ui.control.Label
        MatgTextArea                    matlab.ui.control.TextArea
        MatgTextAreaLabel               matlab.ui.control.Label
        SFCgEditField                   matlab.ui.control.NumericEditField
        SFCgEditFieldLabel              matlab.ui.control.Label
        SFCpEditField                   matlab.ui.control.NumericEditField
        SFCLabel                        matlab.ui.control.Label
        SFBgEditField                   matlab.ui.control.NumericEditField
        SFBgLabel                       matlab.ui.control.Label
        SFBpEditField                   matlab.ui.control.NumericEditField
        SFBpLabel                       matlab.ui.control.Label
        MatpTextArea                    matlab.ui.control.TextArea
        MatpTextAreaLabel               matlab.ui.control.Label
        rpmNgEditField                  matlab.ui.control.NumericEditField
        rpmNgEditFieldLabel             matlab.ui.control.Label
        rpmNpEditField                  matlab.ui.control.NumericEditField
        rpmNpEditFieldLabel             matlab.ui.control.Label
        GearRatioiEditField             matlab.ui.control.NumericEditField
        GearRatioiLabel                 matlab.ui.control.Label
        NoofTeethZgEditField            matlab.ui.control.NumericEditField
        NoofTeethZgEditFieldLabel       matlab.ui.control.Label
        DiameterDgEditField             matlab.ui.control.NumericEditField
        DiameterDgEditFieldLabel        matlab.ui.control.Label
        NoofTeethZpEditFieldLabel       matlab.ui.control.Label
        DiameterDpEditField             matlab.ui.control.NumericEditField
        NoofTeethZpEditField            matlab.ui.control.NumericEditField
        DiameterDpEditFieldLabel        matlab.ui.control.Label
        FaceWidthFEditField             matlab.ui.control.NumericEditField
        FaceWidthFLabel                 matlab.ui.control.Label
        ModuleMEditField                matlab.ui.control.NumericEditField
        ModuleMLabel                    matlab.ui.control.Label
        GearLabel                       matlab.ui.control.Label
        PinionLabel                     matlab.ui.control.Label
        SpurGearDesignProgramLabel      matlab.ui.control.Label
        INPUTPanel                      matlab.ui.container.Panel
        DesignlifeDropDown              matlab.ui.control.DropDown
        DesignlifeDropDownLabel         matlab.ui.control.Label
        ApplicationDropDown             matlab.ui.control.DropDown
        ApplicationDropDown_2Label      matlab.ui.control.Label
        DriverDrivenDropDown            matlab.ui.control.DropDown
        DriverDrivenDropDownLabel       matlab.ui.control.Label
        GearrpmEditField                matlab.ui.control.NumericEditField
        GearrpmEditFieldLabel           matlab.ui.control.Label
        PinionrpmEditField              matlab.ui.control.NumericEditField
        PinionrpmEditFieldLabel         matlab.ui.control.Label
        PowerKWattEditField             matlab.ui.control.NumericEditField
        PowerKWattEditFieldLabel        matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        DialogApp1 % app for user guide
        DialogApp2 % app for ploting
        DialogApp3 %app for about us
        DialogApp4 %app for calling us

        DialogModule %app to get module
        PinionDiam
        Module
        NumberofTeethg
        NumberofTeethp
        
       
    end
    
    methods (Access = public)
                 
        function GetModule_PinionDiam(app,m,Dp)
            app.Module=m;
            app.PinionDiam= Dp;
        end
        
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                
            app.PowerKWattEditField.Value=15;
            app.PinionrpmEditField.Value=700;
            app.GearrpmEditField.Value=200;
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
         
            
            %Inputs
           Power = app.PowerKWattEditField.Value; 
           n1= app.PinionrpmEditField.Value;
           n2= app.GearrpmEditField.Value; 
           %Drop down value to find ko
                                       
                                     %Bending Stress Calculations%
                                           
           %overload Factor, ko                First factor
           switch app.DriverDrivenDropDown.Value
               case 'Uniform Power source with  uniform driven machine'
                   ko =1;
               case 'Uniform Power source with  moderate shock driven machine'
                   ko=1.25;
               case 'Uniform Power source with  heavy shock driven machine'
                   ko=1.75;
               case 'Light shock Power source with  uniform driven machine'
                   ko=1.25;
               case 'Light shock Power source with  moderate shock driven machine'
                   ko=1.5;
               case 'Light shock Power source with  heavy shock driven machine'
                   ko=2;
               case 'Medium shock Power source with  uniform driven machine'
                   ko=1.5;
               case 'Medium shock Power source with  moderate shock driven machine'
                   ko=1.75;
           end  

            %Design Power Calculations
            pd=Power*1000*ko; %power in watt
            Pd=pd/745; %power in horse power as I did not finish my module eq
            %Code below to get module and pinion diameter user input
            
            app.DialogModule=ModuleApp(app);
            m=app.Module;
            Dp=app.PinionDiam;
           
            
            zp=Dp/m;             % Pinion Number of teeth
            app.NumberofTeethp=zp;    %Property Assignment
            i=(n2/n1);           % Gear Ratio
            zg=zp/i;            % Gear Number of teeth
            zg=round (zg);
            app.NumberofTeethg=zg;      %property Assignment
            i=zp/zg;              %exact i///////////////////////
            np=n1;
            Dg=zg*m;             % Gear Diameter
            ng=i*np;
            F=5*pi*m;          % Face Width
            f = F/25.4;       % Convert Face width to in.
            d = Dp/25.4;
            Cm=(Dp+Dg)/2;  %Center Distance/////////////////////////////////////////
            np=n1;
            
            
            %Define Da,Dd,Dsh redflag<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
            Da=Dp+2*m;
            Dd=Dp+2*(1.25*m);
 

            %Dynamic Factor,Kv                          Second Factor
            
            switch app.ApplicationDropDown.Value
                case 'Cement Mixer drum drive'
                    qv=5;
                case 'Cement klin'
                    qv=6;
                case 'Steel mill drives'
                    qv=6;
                case 'Corn picker'
                    qv=7;
                case 'Cranes'
                    qv=7;
                case 'Punch press'
                    qv=7;
                case 'Mining Conveyor'
                    qv=7;
                case 'Paper-box-making machine'
                    qv=8;
                case 'Gas meter mechanism'
                    qv=9;  
                case 'Small Power mill'
                    qv=9;
                case 'Clothes Washing machine'
                    qv=10;
                case 'Printing Press'
                    qv=11;
                case 'Computing Mechanism'
                    qv=11;
                case 'Automotive Transmission'
                    qv=11;
                case 'Radar antenna drive'
                    qv=12;
                case 'Marine Propulsion drive'
                    qv=12;
                case 'Aircraft engine drive'
                    qv=13;
                case 'Gyroscope'
                    qv=14;  
            end
            
            b =.25*(12-qv)^(2/3);        
            a=50+56*(1-b);
            vmax= ((a+(qv-3))^2)/200;    % Maximum Velocity in m/s
            v=3.14*Dp*n1/60000;          % Average Velocity in m/s
            kv=((a+sqrt(200*v))/a)^b;


            % Size Factor, ks                         Third Factor
            %optmizing with for instead of rewriting ////////////
            %z=[zp zg];
            %Y=[0.296 0.309];
            Y=[1 1];
            if zp == 12
                Y1 = 0.245;
            elseif zp == 13
                Y1 = 0.261;
            elseif zp == 14
                Y1 = 0.277;
            elseif zp == 15
                Y1 = 0.290;
            elseif zp == 16
                Y1 = 0.296;
            elseif zp == 17
                Y1 = 0.303;
            elseif zp == 18
                Y1 = 0.309;
            elseif zp == 19
                Y1 = 0.314;
            elseif zp == 20
                Y1 = 0.322;
            elseif zp == 21
                Y1 = 0.328;
            elseif zp == 22
                Y1 = 0.331;
            elseif zp == 23
                Y1 = 0.337;
            elseif zp == 24
                Y1 = 0.346;
            elseif zp == 28
                Y1 = 0.353;
            elseif zp == 30
                Y1 = 0.359;
            elseif zp == 34
                Y1 = 0.371;
            elseif zp == 38
                Y1 = 0.384;
            elseif zp == 43
                Y1 = 0.397;
            elseif zp == 50
                Y1 = 0.409;
            elseif zp == 60
                Y1 = 0.422;
            elseif zp == 75
                Y1 = 0.435;
            elseif zp == 100
                Y1 = 0.447;
            elseif zp == 150
                Y1 = 0.460;
            elseif zp == 300
                Y1 = 0.472;
            elseif zp == 400
                Y1 = 0.480;
            elseif strcmpi(zp, 'Rack')
                Y1 = 0.485;
            end
            
            % Determine Y based on zg for Ks2 
            if zg == 12
                Y2 = 0.245;
            elseif zg == 13
                Y2 = 0.261;
            elseif zg == 14
                Y2 = 0.277;
            elseif zg == 15
                Y2 = 0.290;
            elseif zg == 16
                Y2 = 0.296;
            elseif zg == 17
                Y2 = 0.303;
            elseif zg == 18
                Y2 = 0.309;
            elseif zg == 19
                Y2 = 0.314;
            elseif zg == 20
                Y2 = 0.322;
            elseif zg == 21
                Y2 = 0.328;
            elseif zg == 22
                Y2 = 0.331;
            elseif zg == 23
                Y2 = 0.337;
            elseif zg == 24
                Y2 = 0.346;
            elseif zg == 28
                Y2 = 0.353;
            elseif zg == 30
                Y2 = 0.359;
            elseif zg == 34
                Y2 = 0.371;
            elseif zg == 38
                Y2 = 0.384;
            elseif zg == 43
                Y2 = 0.397;
            elseif zg == 50
                Y2 = 0.409;
            elseif zg == 60
                Y2 = 0.422;
            elseif zg == 75
                Y2 = 0.435;
            elseif zg == 100
                Y2 = 0.447;
            elseif zg == 150
                Y2 = 0.460;
            elseif zg == 300
                Y2 = 0.472;
            elseif zg == 400
                Y2 = 0.480;
            else zg > 400
                Y2 = 0.5;
            
            end


            % Calculate Ks1 for zp
            ksp = 1.192 * ((m / 25.4) * (F / 25.4) * sqrt(Y1))^0.0535;
            
            % Calculate Ks2 for zg
            ksg = 1.192 * ((m / 25.4) * (F / 25.4) * sqrt(Y2))^0.0535;


            %Load Distribution Factor,Km       fourth factor%red fag Capital<<<
  
            % Define Cmc factor               
            Cmc = 1;
                
            
            % Calculate Cpf based on F / (10*d)
            FD_ratio = f / (10 * d);
            if FD_ratio < 0.05
                FD_ratio = 0.05;
            end
            %Red flag<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
            if F <= 1
                Cpf = (f / (10 * d)) - 0.025;
            elseif f > 1 && f <= 17
                Cpf = (f / (10 * d)) - 0.0375 + 0.0125 * f;
            elseif f > 17 && f <= 40
                Cpf = (f / (10 * d)) - 0.1109 + 0.0207 * f - 0.000228 * f^2;
              
            end


            % Calculate Cpm
            
            Cpm = 1;


            % Define Cma constants based on condition
            condition = 'commercial'; % Options: 'open', 'commercial', 'precision', 'extraprecision'
            
            switch condition
               case 'open'
                    A = 0.247; B = 0.0167; C = -0.765e-4;
                case 'commercial'
                    A = 0.127; B = 0.0158; C = -0.930e-4;
                case 'precision'
                    A = 0.0675; B = 0.0128; C = -0.926e-4;
                case 'extraprecision'
                    A = 0.00360; B = 0.0102; C = -0.822e-4;
            end
                
            % Calculate Cma
            
            Cma = A + B * f + C * f^2;
            

            % Define Ce
            
            Ce = 1;
            
            Cpf=1;
            % Calculate Km
            km = 1 + Cmc * (Cpf * Cpm + Cma * Ce);


           %Tangential load, Wt                 Fifth factor
           Wt=pd*1000*(2/Dp)*(60/(2*pi*n1));


           %Rim thickess factor , kb                 6th factor
           kb=1;


           %Geometry Factor, J                     7th factor<<<<<<<<<<
           %img2 = imread('Geometry Factor.jpg');       %Geometry Factor Chart For Jp,Jg
           %imshow(img2);
           %J1=input('Enter Jp For Pinion --> '); 
           %J2=input('Enter Jg For Gear --> ');
           J1= 0.456 ;%J is Constant til<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
           J2= 0.359 ;


           %Calculation Of Bending Stress
           BSp=(Wt*ko*kb*km*kv*ksp)/(F*m*J1);          %For Pinion
           BSg=(Wt*ko*kb*km*kv*ksg)/(F*m*J2);          %For Gear


           %material selection
            kt=1;
            kr=1;
            
            
            switch app.DesignlifeDropDown.Value
                case 'Domestic Appliances & Domestic Electric motor'
                    l=1500;
                case 'Industrial Electric motor'
                    l=25000;
                case 'Elevator & Industrial fans'
                    l=11500;
                case 'Mine Venitilation'
                    l=45000;
                case 'Gearing Units'
                    l=11500;
                case 'Intermittent Service'
                    l=11500;
                case 'Paper Machine'
                    l=55000;
                case 'Pumps'
                    l=50000;
                case 'c'           %Red flag<<<<<<<<<<<<<<<<<<<<<<<<
                    l=30000;
                case 'Compressor'
                    l=50000;
                case 'Blower 8-h Service'
                    l=30000;
                case 'Trucks'
                    l=2000;
                case 'Bus , Car'
                    l=3500;
                case 'Air Craft Jet Engine'
                    l=2725;
                case 'Air Craft engine'
                    l=2000;
                case 'Agriclutural Equipments'
                    l=4500;
            end
            
            Np=60*l*n1;
            Ng=60*l*n2;
            
            %optmizing with for instead of rewriting ////////////
            N=[Np Ng];
            %Reusing last declared & used vector Y in ks calculations
            for g=1:1:2
                if N(g) < 5*10^6
                    Y(g)=9.4518*N(g)^(-.148);
                elseif N(g)>5*10^6
                    Y(g)=1.3558*N(g)^(-.0178);
                end
            end
            
            Yp=Y(1);
            Yg=Y(2);
            
            
            st= [35 40 69 70 90 110 160 230 280 325 360 380 390 520];
            %Edit use of Greater than instead of equal////////////
            %using of nested for//////////////////////
            %Reusing last declared & used vector Y in ks calculations
            BS=[BSp BSg]; %Container Vector
            for g=1:1:2
                for h=1:1:numel(st)
                   if st(h)>BS(g) %Eliminating of equal///////////
                       Y(g)=st(h);
                       break;
                   end
                end
            end    

            stp= Y(1);
            stg= Y(2);
            ST=[stp stg]; %Cotainer Vector
           
            for g=1:1:2
                if (ST(g) > 0 && ST(g) <= 35 )
                    
                    if g==1
                        Matp1='Casr iron 20';
                    else
                        Matg1='Casr iron 20';
                    end
                elseif (ST(g) > 35 && ST(g) <= 40 )
                    
                    if g==1
                        Matp1='Bronze 2';
                    else
                        Matg1='Bronze 2';
                    end
                elseif (ST(g) > 40 && ST(g) <= 69 )
                    
                    if g==1
                        Matp1='Casr iron 30';
                    else
                        Matg1='Casr iron 30';
                    end                                 
                elseif (ST(g) > 69 && ST(g) <= 70 )
                                               
                    if g==1
                        Matp1='Malleable iron A-8-c';
                    else
                        Matg1='Malleable iron A-8-c';
                    end
                elseif (ST(g) > 70 && ST(g) <= 90 )
                                              
                    if g==1
                        Matp1='Malleable iron A-8-e';
                    else
                        Matg1='Malleable iron A-8-e';
                    end
                elseif (ST(g) > 90 && ST(g) <= 110 )
                    
                    if g==1
                        Matp1='Malleable iron A-8-f';
                    else
                        Matg1='Malleable iron A-8-f';
                    end
                elseif (ST(g) > 110 && ST(g) <= 145 )
                    if g==1
                        Matp1='Malleable iron A-8-i';
                    else
                        Matg1='Malleable iron A-8-i';
                    end                          
                elseif (ST(g) > 145 && ST(g) <= 160 )
                                                  
                    if g==1
                        Matp1='Bronze AI/Br 3';
                    else
                        Matg1='Bronze AI/Br 3';
                    end
                elseif (ST(g) > 160 && ST(g) <= 230 )

                    if g==1
                        Matp1='Steel A1';
                    else
                        Matg1='Steel A1 ';
                    end
                elseif (ST(g) > 230 && ST(g) <= 280 )
                   
                    if g==1
                        Matp1='Steel A2';
                    else
                        Matg1='Steel A2';
                    end
                elseif (ST(g) > 280 && ST(g) <= 325 )
                
                    if g==1
                        Matp1='Steel A3';
                    else
                        Matg1='Steel A3';
                    end
                elseif (ST(g) > 325 && ST(g) <= 360 )
                      
                    if g==1
                        Matp1='Steel A4';
                    else
                        Matg1='Steel A4';
                    end
                elseif (ST(g) > 360 && ST(g) <= 380 )
                    
                    if g==1
                        Matp1='Steel flame or induction hardened Heat Treated';
                    else
                        Mat1(1)='Steel flame or induction hardened Heat Treated';
                    end
                elseif (ST(g) > 380 && ST(g) <= 390 )
                 
                    if g==1
                        Matp1='Steel A5';
                    else
                        Matg1='Steel A5';
                    end
                elseif (ST(g) > 390 && ST(g) <= 520 )
                  
                    if g==1
                        Matp1='Steel carburized and case hardened Heat Treated';
                    else
                        Matg1='Steel carburized and case hardened Heat Treated';
                    end
                end
            end
             Mat1=[Matp1 Matg1];%isnot used due to our code limitation for contact material only
           
            %Allowable Bending Stress Calculation for pinion and gear
            SABp=(stp*Yp)/(kt*kr);
            SABg=(stg*Yg)/(kt*kr);
            %Safety factor of Bending for Gear and pinion
            SFp=SABp/BSp; 
            SFg=SABg/BSg;
          
                                            %Contact Stress Calculations%

            cp=191;
            mg=1/i;
            %I= (cos(20)*sin(20)*mg)/(2*1*(mg+1))
            I=((cos(20)*sin(20))*mg)/(2*(mg+1));
            cf=1;
            CSp =cp*((Wt*ko*kv*km*ksp*cf)/(Dp*F*I))^0.5;           % Contact σc Stress for Gear 
            
            CSg =cp*((Wt*ko*kv*km*ksg*cf)/(Dg*F*I))^0.5;           % Contact σc Stress for Pinion


            %Using of for loop////////////////////////////////////
            %Reusing last declared & used vector Y in ks calculations
            

            for g=1:1:2
                if N(g) < 5*10^6
                    Y(g)=2.466*N(g)^(-.056);
                elseif N(g)>5*10^6
                    Y(g)=1.4488*N(g)^(-.023);
                end
            end
      
            Zp=Y(1);
            Zg=Y(2);
            
            
            sc= [410 450 520  660 790 870 930 1100 1200 1300 ];
            chp=1.1;
            chg=1.14;
            kt=1;
            kr=1;
            %Use of nested for loop////////////////////////
            CS=[CSp CSg] ;   %Container Vector
            %Reusing last declared & used vector Y in ks calculations
            for g=1:1:2
                for h=1:1:numel(sc)
                   if sc(h)>CS(g)       %eliminating of equal/////////////
                       Y(g)=sc(h);
                       break;
                   end
                end
            end 
            scp=Y(1);
            scg=Y(2);
            
            %Use of For loop//////////////////////////////
            
            SC=[scp scg];
            for g=1:1:2
                if (SC(g) > 0 && SC(g) <= 410 )
                            
                    
                    if g==1
                        Matp2='Cast iron 20';
                    else
                        Matg2='Cast iron 20';
                    end
                elseif (SC(g) > 410 && SC(g) <= 450)
                   
                    if g==1
                        Matp2='Bronze 2';
                    else
                        Matg2='Bronze 2';
                    end
                    
                elseif (SC(g) > 450 && SC(g) <= 500 )
                 
                    if g==1
                        Matp2='Malleable iron A-8-c';
                    else
                        Matg2='Malleable iron A-8-c';
                    end
                elseif (SC(g) > 500 && SC(g) <= 520 )
                    
                    if g==1
                        Matp2='Cast iron 30';
                    else
                        Matg2='Cast iron 30';
                    end
                elseif (SC(g) > 520 && SC(g) <= 540 )
                    
                    if g==1
                        Matp2='Malleable iron A-8-e';
                    else
                        Matg2='Malleable iron A-8-e';
                    end
                elseif (SC(g) > 540 && SC(g) <= 590 )
                    
                    if g==1
                        Matp2='Cast iron 40';
                    else
                        Matg2='Cast iron 40';
                    end
                elseif (SC(g) > 590 && SC(g) <= 650 )
                   
                    if g==1
                        Matp2='Malleable iron A-8-i';
                    else
                        Matg2='Malleable iron A-8-i';
                    end
                elseif (SC(g) > 650 && SC(g) <= 660 )
                  
                    if g==1
                        Matp2='Steel A1';
                    else
                        Matg2='Steel A1';
                    end
                elseif (SC(g) > 660 && SC(g) <= 790 )
               
                    if g==1
                        Matp2='Steel A2';
                    else
                        Matg2='Steel A2';
                    end
                elseif (SC(g) > 790 && SC(g) <= 930 )
                    
                    if g==1
                        Matp2='Steel A3';
                    else
                        Matg2='Steel A3';
                    end
                elseif (SC(g) > 930 && SC(g) <= 1100 )
                  
                    if g==1
                        Matp2='Steel A4';
                    else
                        Matg2='Steel A4';
                    end
                elseif (SC(g) > 1100 && SC(g) <= 1200 )
                   
                    if g==1
                        Matp2='Steel A5';
                    else
                        Matg2='Steel A5';
                    end
                elseif (SC(g) > 1200 && SC(g) <= 1250 )
                   
                    if g==1
                        Matp2='Steel Flame or induction hardened Heat Treated';
                    else
                        Matg2='Steel Flame or induction hardened Heat Treated';
                    end
                elseif (SC(g) > 1250 && SC(g) <=1300 )
                  
                    if g==1
                        Matp2='Steel Carburized and case hardened Heat Treated';
                    else
                        Matg2='Steel Carburized and case hardened Heat Treated';
                    end
                end
            end


            SACp=(scp*Zp*chp)/(kt*kr);         %Allowable contact strength for pinion
            SACg=(scg*Zg*chg)/(kt*kr);         %Allowable contact strength for gear  
            SFCp=SACp/CSp;                     %Safety factor for pinion
            SFCg=SACg/CSg;                     %Safety factor for gear


            %OutPut
           app.CenterDistanceCmEditField.Value= Cm;
           app.ModuleMEditField.Value =m;
           app.FaceWidthFEditField.Value =F;
           app.DiameterDpEditField.Value =Dp;
           app.DiameterDgEditField.Value =Dg;
           app.NoofTeethZpEditField.Value =zp;
           app.NoofTeethZgEditField.Value =zg;
           app.GearRatioiEditField.Value =i;
           app.rpmNpEditField.Value =np;
           app.rpmNgEditField.Value =ng;
           app.SFBpEditField.Value =SFp;
           app.SFBgEditField.Value =SFg;
           app.SFCpEditField.Value=SFCp;
           app.SFCgEditField.Value=SFCg;
           app.MatpTextArea.Value=Matp2;
           app.MatgTextArea.Value=Matg2;

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %Reset Input
            app.PowerKWattEditField.Value =0;
            app.PinionrpmEditField.Value =0;
            app.GearrpmEditField.Value =0;
            
            %Reset Output
            app.CenterDistanceCmEditField.Value =0;
            app.ModuleMEditField.Value =0;
            app.FaceWidthFEditField.Value =0;
            app.DiameterDpEditField.Value =0;
            app.DiameterDgEditField.Value =0;
            app.NoofTeethZpEditField.Value =0;
            app.NoofTeethZgEditField.Value =0;
            app.GearRatioiEditField.Value =0;
            app.rpmNpEditField.Value =0;
            app.rpmNgEditField.Value =0;
            app.SFBpEditField.Value =0;
            app.SFBgEditField.Value =0;
            app.SFCpEditField.Value=0;
            app.SFCgEditField.Value=0;
            app.MatpTextArea.Value='Reseted';
            app.MatgTextArea.Value='Reseted';

            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
                % Disable Options button while dialog is open
                %app.SolveButton.Enable = "off"; %I can not renable
                %app.ResetButton.Enable = "off"; %I can not renable
                
                % Call dialog box with input values
                app.DialogApp1 = Questionapp(app);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
               % Delete both apps
                delete(app.DialogApp1)
                delete(app.DialogApp2)
                delete(app.DialogApp3)
                delete(app.DialogApp4)
                delete(app.DialogModule)
                delete(app)        
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
                % Disable Plot Options button while dialog is open
                %app.PlotButton.Enable = "off";   %I can not renable
                
   
                m=app.Module;
                zp=app.NumberofTeethp;
                zg=app.NumberofTeethg;
                app.DialogApp2 = PlotGears(app,m,zp,zg);%redflag<<<<<<<<<<<<<<<<<<<<<<Undefined variables =solved
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
                app.DialogApp3 = GearAboutusApp(app);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
                app.DialogApp4 = GearCallusApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2024-12-22 at 10.11.30_a651165f.jpg');
            app.INPUTPanel = uipanel(app.UIFigure);
            app.INPUTPanel.Title = 'INPUT';
            app.PowerKWattEditFieldLabel = uilabel(app.INPUTPanel);
            app.PowerKWattEditFieldLabel.Text = 'Power(KWatt)';
            app.PowerKWattEditField = uieditfield(app.INPUTPanel, 'numeric');
            app.PinionrpmEditFieldLabel = uilabel(app.INPUTPanel);
            app.PinionrpmEditFieldLabel.Text = 'Pinion r.p.m';
            app.PinionrpmEditField = uieditfield(app.INPUTPanel, 'numeric');
            app.GearrpmEditFieldLabel = uilabel(app.INPUTPanel);
            app.GearrpmEditFieldLabel.Text = 'Gear r.p.m';
            app.GearrpmEditField = uieditfield(app.INPUTPanel, 'numeric');
            app.DriverDrivenDropDownLabel = uilabel(app.INPUTPanel);
            app.DriverDrivenDropDownLabel.Text = 'Driver, Driven';
            app.DriverDrivenDropDown = uidropdown(app.INPUTPanel);
            app.DriverDrivenDropDown.Items = {'Uniform Power source with  uniform driven machine', 'Uniform Power source with  moderate shock driven machine', 'Uniform Power source with  heavy shock driven machine', 'Light shock Power source with  uniform driven machine', 'Light shock Power source with  moderate shock driven machine', 'Light shock Power source with  heavy shock driven machine', 'Medium shock Power source with  uniform driven machine', 'Medium shock Power source with  moderate shock driven machine', 'Medium shock Power source with  heavy shock driven machine'};
            app.DriverDrivenDropDown.Value = 'Uniform Power source with  uniform driven machine';
            app.ApplicationDropDown_2Label = uilabel(app.INPUTPanel);
            app.ApplicationDropDown_2Label.Text = 'Application';
            app.ApplicationDropDown = uidropdown(app.INPUTPanel);
            app.ApplicationDropDown.Items = {'Cement Mixer drum drive', 'Cement klin', 'Steel mill drives', 'Corn picker', 'Cranes', 'Punch press', 'Mining Conveyor', 'Paper-box-making machine', 'Gas meter mechanism', 'Computing Mechanism', 'Automotive Transmission', 'Radar antenna drive', 'Marine Propulsion drive', 'Aircraft engine drive', 'Gyroscope'};
            app.ApplicationDropDown.Value = 'Cement Mixer drum drive';
            app.DesignlifeDropDownLabel = uilabel(app.INPUTPanel);
            app.DesignlifeDropDownLabel.Text = 'Design life';
            app.DesignlifeDropDown = uidropdown(app.INPUTPanel);
            app.DesignlifeDropDown.Items = {'Domestic Appliances & Domestic Electric motor', 'Industrial Electric motor', 'Elevator & Industrial fans', 'Mine Venitilation', 'Gearing Units', 'Intermittent Service', 'Paper Machine', 'Blower 8-h Service', 'Compressor', 'Trucks', 'Option 11', 'Option 12', 'Option 9', 'Option 13'};
            app.DesignlifeDropDown.Value = 'Domestic Appliances & Domestic Electric motor';
            app.SpurGearDesignProgramLabel = uilabel(app.UIFigure);
            app.SpurGearDesignProgramLabel.Text = 'Spur Gear Design Program';
            app.OUTPUTPanel = uipanel(app.UIFigure);
            app.OUTPUTPanel.Title = 'OUTPUT';
            app.PinionLabel = uilabel(app.OUTPUTPanel);
            app.PinionLabel.Text = 'Pinion';
            app.GearLabel = uilabel(app.OUTPUTPanel);
            app.GearLabel.Text = 'Gear';
            app.ModuleMLabel = uilabel(app.OUTPUTPanel);
            app.ModuleMLabel.Text = 'Module, M           ';
            app.ModuleMEditField = uieditfield(app.OUTPUTPanel, 'numeric');
            app.FaceWidthFLabel = uilabel(app.OUTPUTPanel);
            app.FaceWidthFLabel.Text = 'Face Width, F        ';
            app.FaceWidthFEditField = uieditfield(app.OUTPUTPanel, 'numeric');
            app.DiameterDpEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.DiameterDpEditFieldLabel.Text = 'Diameter, Dp';
            app.NoofTeethZpEditField = uieditfield(app.OUTPUTPanel, 'numeric');
            app.DiameterDpEditField = uieditfield(app.OUTPUTPanel, 'numeric');
            app.NoofTeethZpEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.NoofTeethZpEditFieldLabel.Text = 'No. of Teeth, Zp';
            app.DiameterDgEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.DiameterDgEditFieldLabel.Text = 'Diameter, Dg';
            app.DiameterDgEditField = uieditfield(app.OUTPUTPanel, 'numeric');
            app.NoofTeethZgEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.NoofTeethZgEditFieldLabel.Text = 'No. of Teeth, Zg';
            app.NoofTeethZgEditField = uieditfield(app.OUTPUTPanel, 'numeric');
            app.GearRatioiLabel = uilabel(app.OUTPUTPanel);
            app.GearRatioiLabel.Text = 'Gear Ratio, i         ';
            app.GearRatioiEditField = uieditfield(app.OUTPUTPanel, 'numeric');
            app.rpmNpEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.rpmNpEditFieldLabel.Text = 'r.p.m, Np';
            app.rpmNpEditField = uieditfield(app.OUTPUTPanel, 'numeric');
            app.rpmNgEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.rpmNgEditFieldLabel.Text = 'r.p.m, Ng';
            app.rpmNgEditField = uieditfield(app.OUTPUTPanel, 'numeric');
            app.MatpTextAreaLabel = uilabel(app.OUTPUTPanel);
            app.MatpTextAreaLabel.Text = 'Matp';
            app.MatpTextArea = uitextarea(app.OUTPUTPanel);
            app.SFBpLabel = uilabel(app.OUTPUTPanel);
            app.SFBpLabel.Text = 'S.F.Bp';
            app.SFBpEditField = uieditfield(app.OUTPUTPanel, 'numeric');
            app.SFBgLabel = uilabel(app.OUTPUTPanel);
            app.SFBgLabel.Text = 'S.F.Bg';
            app.SFBgEditField = uieditfield(app.OUTPUTPanel, 'numeric');
            app.SFCLabel = uilabel(app.OUTPUTPanel);
            app.SFCLabel.Text = 'S.F.Cp';
            app.SFCpEditField = uieditfield(app.OUTPUTPanel, 'numeric');
            app.SFCgEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.SFCgEditFieldLabel.Text = 'S.F.Cg';
            app.SFCgEditField = uieditfield(app.OUTPUTPanel, 'numeric');
            app.MatgTextAreaLabel = uilabel(app.OUTPUTPanel);
            app.MatgTextAreaLabel.Text = 'Matg';
            app.MatgTextArea = uitextarea(app.OUTPUTPanel);
            app.CenterDistanceCmEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.CenterDistanceCmEditFieldLabel.Text = 'Center Distance, Cm';
            app.CenterDistanceCmEditField = uieditfield(app.OUTPUTPanel, 'numeric');
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Icon = fullfile(pathToMLAPP, 'idea.png');
            app.SolveButton.Text = 'Solve';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = fullfile(pathToMLAPP, 'reboot.png');
            app.ResetButton.Text = 'Reset';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'engineering.png');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'engineering.png');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'question.png');
            app.Button.Text = '';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Icon = fullfile(pathToMLAPP, 'chart.png');
            app.PlotButton.Text = 'Plot';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'user_18229706.png');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'telephone_15694783.png');
            app.Button_3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GearDesignApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end