classdef ModuleApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CloseButton                   matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        PinionDiameterEditField       matlab.ui.control.NumericEditField
        PinionDiameterEditFieldLabel  matlab.ui.control.Label
        EnterModuleEditField          matlab.ui.control.NumericEditField
        EnterModuleEditFieldLabel     matlab.ui.control.Label
        okButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, count)
           
            app.EnterModuleEditField.Value=0;
            app.PinionDiameterEditField.Value=0;
             
            % Store main app in property
            app.MainApp = mainapp;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: okButton
        function okButtonPushed(app, event)
            if app.EnterModuleEditField.Value~=0 && app.PinionDiameterEditField.Value~=0
                m=app.EnterModuleEditField.Value;
                Dp=app.PinionDiameterEditField.Value;
                GetModule_PinionDiam(app.MainApp, m, Dp);
                
                delete(app)
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.okButton = uibutton(app.UIFigure, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.okButton.Icon = fullfile(pathToMLAPP, 'checked.png');
            app.okButton.Text = 'ok';
            app.EnterModuleEditFieldLabel = uilabel(app.UIFigure);
            app.EnterModuleEditFieldLabel.Text = 'Enter Module';
            app.EnterModuleEditField = uieditfield(app.UIFigure, 'numeric');
            app.PinionDiameterEditFieldLabel = uilabel(app.UIFigure);
            app.PinionDiameterEditFieldLabel.Text = 'Pinion Diameter';
            app.PinionDiameterEditField = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2024-12-07 at 07.44.33_29837910.jpg');
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Icon = fullfile(pathToMLAPP, 'cancel.png');
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModuleApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end