classdef PlotGears < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        okButton                matlab.ui.control.Button
        GearPanel               matlab.ui.container.Panel
        DedundumOg              matlab.ui.control.NumericEditField
        DedundumOLabel          matlab.ui.control.Label
        BaseOg                  matlab.ui.control.NumericEditField
        BaseOEditField_2Label   matlab.ui.control.Label
        PitchOg                 matlab.ui.control.NumericEditField
        PitchOEditField_2Label  matlab.ui.control.Label
        AdendumOg               matlab.ui.control.NumericEditField
        AdendumOLabel           matlab.ui.control.Label
        PinionPanel             matlab.ui.container.Panel
        DedundumOp              matlab.ui.control.NumericEditField
        DedundumOLabel_2        matlab.ui.control.Label
        BaseOp                  matlab.ui.control.NumericEditField
        BaseOEditFieldLabel     matlab.ui.control.Label
        PitchOp                 matlab.ui.control.NumericEditField
        PitchOEditFieldLabel    matlab.ui.control.Label
        AdendumOp               matlab.ui.control.NumericEditField
        AdendumOLabel_2         matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, GearDesignApp, m, zp, zg)
            %delete(app);
            
            z=[zp zg]; %Container for number of teeth
            A=[0 0]; %Adendum Circle vector Container
            P=[0 0]; %Pitch Circle vector Container
            B=[0 0]; %Base Circle vector Container
            D=[0 0]; %ddendum Circle vector Container
            for g=1:1:2 %1 for pinion and 2 for gear
                A(g)=m*(z(g)+2);
                P(g)=m*z(g);
                B(g)=m*z(g)*cos(20*(pi/180));
                D(g)=m*(z(g)-2.5);
            end


            %output
            app.AdendumOp.Value=A(1);
            app.AdendumOg.Value=A(2);
            app.PitchOp.Value=P(1);
            app.PitchOg.Value=P(2);
            app.BaseOp.Value=B(1);
            app.BaseOg.Value=B(2);
            app.DedundumOp.Value=D(1);
            app.DedundumOg.Value=D(2);
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
             % Enable the Plot Options button in main app, if the app is
            % still open
            %if isvalid(app.MainApp)
             %   app.MainApp.PlotButton.Enable = "on";
            %end
                    
            % Delete the dialog box 
            delete(app)             
                    
        end

        % Button pushed function: okButton
        function okButtonPushed(app, event)
            %app.MainApp.PlotButton.Enable = "on";
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PinionPanel = uipanel(app.UIFigure);
            app.PinionPanel.Title = 'Pinion';
            app.AdendumOLabel_2 = uilabel(app.PinionPanel);
            app.AdendumOLabel_2.Text = 'AdendumO ';
            app.AdendumOp = uieditfield(app.PinionPanel, 'numeric');
            app.PitchOEditFieldLabel = uilabel(app.PinionPanel);
            app.PitchOEditFieldLabel.Text = 'PitchO    ';
            app.PitchOp = uieditfield(app.PinionPanel, 'numeric');
            app.BaseOEditFieldLabel = uilabel(app.PinionPanel);
            app.BaseOEditFieldLabel.Text = 'BaseO    ';
            app.BaseOp = uieditfield(app.PinionPanel, 'numeric');
            app.DedundumOLabel_2 = uilabel(app.PinionPanel);
            app.DedundumOLabel_2.Text = 'DedundumO ';
            app.DedundumOp = uieditfield(app.PinionPanel, 'numeric');
            app.GearPanel = uipanel(app.UIFigure);
            app.GearPanel.Title = 'Gear';
            app.AdendumOLabel = uilabel(app.GearPanel);
            app.AdendumOLabel.Text = 'AdendumO ';
            app.AdendumOg = uieditfield(app.GearPanel, 'numeric');
            app.PitchOEditField_2Label = uilabel(app.GearPanel);
            app.PitchOEditField_2Label.Text = 'PitchO    ';
            app.PitchOg = uieditfield(app.GearPanel, 'numeric');
            app.BaseOEditField_2Label = uilabel(app.GearPanel);
            app.BaseOEditField_2Label.Text = 'BaseO    ';
            app.BaseOg = uieditfield(app.GearPanel, 'numeric');
            app.DedundumOLabel = uilabel(app.GearPanel);
            app.DedundumOLabel.Text = 'DedundumO ';
            app.DedundumOg = uieditfield(app.GearPanel, 'numeric');
            app.okButton = uibutton(app.UIFigure, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.okButton.Icon = fullfile(pathToMLAPP, 'checked.png');
            app.okButton.Text = 'ok';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2024-12-23 at 14.20.03_9ea36480.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotGears(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end